classdef Aplikacja_Projekt_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
        Label                          matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        ModulacjaAMButton              matlab.ui.control.Button
        CzestotliwoscsygnalunosnegoLabel  matlab.ui.control.Label
        ModulacjaFMButton              matlab.ui.control.Button
        KonfiguracjasygnauPanel        matlab.ui.container.Panel
        AmplitudaatbLabel              matlab.ui.control.Label
        CzestotliwoscLabel             matlab.ui.control.Label
        aEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        fEditFieldLabel                matlab.ui.control.Label
        fEditField                     matlab.ui.control.NumericEditField
        SygnalmodulujacyDropDownLabel  matlab.ui.control.Label
        SygnalmodulujacyDropDown       matlab.ui.control.DropDown
        Panel                          matlab.ui.container.Panel
        ModulatorAMFMLabel             matlab.ui.control.Label
        PrzyblizButton                 matlab.ui.control.Button
        PrzyblizButton_2               matlab.ui.control.Button
    end

    
    properties (Access = public)
        f1 = 0; % Description
        y01 = 0;
        y11 = 0;
        y02 = 0;
        y12 = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ModulacjaAMButton
        function ModulacjaAMButtonPushed(app, event)
    % AM
    
    fs = 8e6;
    t = 0:1/fs:0.05-1/fs;
    
    % a = 1 => stala amplituda
    % a > 1 => narastajaca amplituda
    % a < 1 => malejaca amplituda
    % b - poczatkowa amplituda
    
    a = app.aEditField.Value;
    b = app.bEditField.Value;
    app.f1 = app.fEditField.Value;
    A1 = a*t+b;
    
    signaltype = string(app.SygnalmodulujacyDropDown.Value);
    if signaltype == "Sinusoida"
        s1 = A1.*sin(2*pi*app.f1*t);
    elseif signaltype == "Chirp"
        s1 = A1.*chirp(t,app.f1-500,t(length(t)),app.f1+500);
    elseif signaltype == "Bialy szum"
        s1 = randn(1,length(t));
        s1 = bandpass(s1,[app.f1-500,app.f1+500],fs);
        s1 = A1.*s1;
    end
    
    % definicja sygnalu nosnego
    f2 = app.Slider.Value;
    s2 = sin(2*pi*f2*t);
    
    % sygnal zmodulowany
    s_am = s1.*s2 + s2;
    
    cla(app.UIAxes, 'reset') 
    plot(app.UIAxes, t, s_am, t, s2, t, s1)
    legend(app.UIAxes, 'zmodulowany', 'modulowany','modulujacy')
    xlabel(app.UIAxes,'czas [s]')
    ylabel(app.UIAxes,'amplituda [~]')
    title(app.UIAxes,'sygnaly')
    xlim(app.UIAxes, [0 0.05])
    
    % wlasny hilbert
    N = length(s_am);
    
    s_am_f = fft(s_am);

    s_am_hf = zeros(1,length(s_am));
    for k = 1:(N+1)/2
        s_am_hf(k) = -1i*sign((N+1)/2 - k)*sign(k)*s_am_f(k);
    end
    
    s_am_h = ifft(s_am_hf) *2;
    env1 = abs(s_am_h);
    
    cla(app.UIAxes2_2, 'reset')
    plot(app.UIAxes2_2, t, s_am, t, env1)
    legend(app.UIAxes2_2,'zmodulowany', 'obwiednia')
    xlabel(app.UIAxes2_2,'czas [s]')
    ylabel(app.UIAxes2_2,'amplituda [~]')
    title(app.UIAxes2_2,'sygnal')
    
    % demodulacja
    s_dem = s_am ./ env1;
    cla(app.UIAxes2, 'reset')
    plot(app.UIAxes2,t,s_dem)
    xlabel(app.UIAxes2,'czas [s]')
    ylabel(app.UIAxes2,'amplituda [~]')
    title(app.UIAxes2,'sygnal zdemodulowany')
    ylim(app.UIAxes2,[-1.5 1.5])
      
 
        end

        % Button pushed function: ModulacjaFMButton
        function ModulacjaFMButtonPushed(app, event)
     
     fs = 4e6;
     t = 0:1/fs:0.1;
     fvec = 0: fs/length(t) : fs-fs/length(t);
     
            
    % a = 1 => stala amplituda
    % a > 1 => narastajaca amplituda
    % a < 1 => malejaca amplituda
    % b - poczatkowa amplituda
    
    app.f1 = app.fEditField.Value;
    a = app.aEditField.Value;
    b = app.bEditField.Value;
    A1 = a*t+b;
    
    signaltype = string(app.SygnalmodulujacyDropDown.Value);
    if signaltype == "Sinusoida"
        s1 = A1.*sin(2*pi*app.f1*t);
        Flow = app.f1-100;
        Fhigh = app.f1+500;
    elseif signaltype == "Chirp"
        s1 = A1.*chirp(t,app.f1-500,t(length(t)),app.f1+500);
        Flow = app.f1-550;
        Fhigh = app.f1+750;
    elseif signaltype == "Bialy szum"
        s1 = randn(1,length(t));
        s1 = bandpass(s1,[app.f1-500,app.f1+500],fs);
        s1 = A1.*s1;
        Flow = app.f1-550;
        Fhigh = app.f1+750;
    end
    
    % definicja sygnalu nosnego
    f2 = round(app.Slider.Value,-3);

    s2 = sin(2*pi*f2*t);  
    
    cla(app.UIAxes, 'reset')   
    semilogy(app.UIAxes, fvec,abs(2*fft(s1)/length(t)));
    title(app.UIAxes, 'Sygnal przed modulacja');
    xlabel(app.UIAxes, 'Czestotliwosc [Hz]');
    ylabel(app.UIAxes, 'Amplituda [~]')
    xlim(app.UIAxes, [500 fvec(length(fvec))/2])  
    app.y11 = max(abs(2*fft(s1)/length(t)));
    app.y01 = app.y11/1e3;
    
    % modulacja
    s_fm = sin(2*pi*t*f2 + s1);
    cla(app.UIAxes2_2, 'reset')
    semilogy(app.UIAxes2_2,fvec,abs(2*fft(s_fm)/length(t)));
    title(app.UIAxes2_2,'Zmodulowany sygnal');
    xlabel(app.UIAxes2_2,'Czestotliwosc [Hz]');
    ylabel(app.UIAxes2_2,'Amplituda [~]')
    xlim(app.UIAxes2_2,[500 fvec(length(fvec))/2])
                    
    % demodulacja FM
    s_diff = diff(s_fm);
%     fvec1 = fvec(1:length(fvec)-1);
    
    % hilbert
    s_fm_f = fft(s_diff);
    N = length(s_fm_f);
    
    s_fm_hf = zeros(1,length(s_fm));
    for k = 1:(N+1)/2
        s_fm_hf(k) = -1i*sign((N+1)/2 - k)*sign(k)*s_fm_f(k);
    end
    
    s_fm_h = ifft(s_fm_hf) *2;
    env1 = abs(s_fm_h);
    
    % dalsza demodulacja
    
    env1 = env1 - mean(env1);
    s_filt  = bandpass(env1,[Flow,Fhigh],fs);
    
    s = length(t);
    s_demod = inteFD(s_filt,1/fs)*s/2;            %calkowanie dla kompensacji zmiany fazy      
            
    cla(app.UIAxes2, 'reset')
    semilogy(app.UIAxes2,fvec,abs(2*fft(s_demod)/length(t)));
    title(app.UIAxes2,'Sygnal po demodulacji');
    xlabel(app.UIAxes2,'Czestotliwosc [Hz]');
    ylabel(app.UIAxes2,'Amplituda [~]')
    app.y12 = max(abs(2*fft(s_demod)/length(t)));
    app.y02 = app.y12/1e3;
    xlim(app.UIAxes2,[500 fvec(length(fvec))/2])


        end

        % Button pushed function: PrzyblizButton
        function Przybliz(app, event)
    xlim(app.UIAxes, [500 2*app.f1])  
    ylim(app.UIAxes,[app.y01 app.y11])

    xlim(app.UIAxes2_2,[500 2*app.f1])
        
    xlim(app.UIAxes2,[500 2*app.f1])
    ylim(app.UIAxes2,[app.y02 app.y12])
        end

        % Button pushed function: PrzyblizButton_2
        function PrzyblizButton_2Pushed(app, event)
    xlim(app.UIAxes, [0 0.01])  

    xlim(app.UIAxes2_2,[0 0.01])
        
    xlim(app.UIAxes2,[0 0.01]) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sygnal przed modulacja')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Sygnal po demodulacji')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Sygnal zmodulowany')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [100000 1000000];
            app.Slider.Value = 100000;
            app.ModulacjaAMButton = uibutton(app.UIFigure, 'push');
            app.ModulacjaAMButton.ButtonPushedFcn = createCallbackFcn(app, @ModulacjaAMButtonPushed, true);
            app.ModulacjaAMButton.Text = 'Modulacja AM';
            app.CzestotliwoscsygnalunosnegoLabel = uilabel(app.UIFigure);
            app.CzestotliwoscsygnalunosnegoLabel.Text = 'Czestotliwosc sygnalu nosnego';
            app.ModulacjaFMButton = uibutton(app.UIFigure, 'push');
            app.ModulacjaFMButton.ButtonPushedFcn = createCallbackFcn(app, @ModulacjaFMButtonPushed, true);
            app.ModulacjaFMButton.Text = 'Modulacja FM';
            app.KonfiguracjasygnauPanel = uipanel(app.UIFigure);
            app.KonfiguracjasygnauPanel.Title = 'Konfiguracja sygnału';
            app.AmplitudaatbLabel = uilabel(app.KonfiguracjasygnauPanel);
            app.AmplitudaatbLabel.Text = 'Amplituda (at + b)';
            app.CzestotliwoscLabel = uilabel(app.KonfiguracjasygnauPanel);
            app.CzestotliwoscLabel.Text = 'Czestotliwosc';
            app.aEditFieldLabel = uilabel(app.KonfiguracjasygnauPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.KonfiguracjasygnauPanel, 'numeric');
            app.bEditFieldLabel = uilabel(app.KonfiguracjasygnauPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.KonfiguracjasygnauPanel, 'numeric');
            app.bEditField.Value = 1;
            app.fEditFieldLabel = uilabel(app.KonfiguracjasygnauPanel);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.KonfiguracjasygnauPanel, 'numeric');
            app.fEditField.Limits = [1000 10000];
            app.fEditField.Value = 1000;
            app.SygnalmodulujacyDropDownLabel = uilabel(app.KonfiguracjasygnauPanel);
            app.SygnalmodulujacyDropDownLabel.Text = 'Sygnal modulujacy';
            app.SygnalmodulujacyDropDown = uidropdown(app.KonfiguracjasygnauPanel);
            app.SygnalmodulujacyDropDown.Items = {'Sinusoida', 'Chirp', 'Bialy szum', ''};
            app.SygnalmodulujacyDropDown.Value = 'Sinusoida';
            app.Panel = uipanel(app.UIFigure);
            app.ModulatorAMFMLabel = uilabel(app.Panel);
            app.ModulatorAMFMLabel.Text = 'Modulator AM/FM';
            app.PrzyblizButton = uibutton(app.UIFigure, 'push');
            app.PrzyblizButton.ButtonPushedFcn = createCallbackFcn(app, @Przybliz, true);
            app.PrzyblizButton.Text = 'Przybliz';
            app.PrzyblizButton_2 = uibutton(app.UIFigure, 'push');
            app.PrzyblizButton_2.ButtonPushedFcn = createCallbackFcn(app, @PrzyblizButton_2Pushed, true);
            app.PrzyblizButton_2.Text = 'Przybliz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aplikacja_Projekt_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end